classdef Caracterizacion_de_Termopares < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CaracterizacindeTermoparesUIFigure  matlab.ui.Figure
        SeleccionarArchivoLabel         matlab.ui.control.Label
        AbrirButton                     matlab.ui.control.Button
        CalcularporButtonGroup          matlab.ui.container.ButtonGroup
        GradoButton                     matlab.ui.control.RadioButton
        CoeficientedePearsonButton      matlab.ui.control.RadioButton
        TemperaturacomovariableButtonGroup  matlab.ui.container.ButtonGroup
        TVButton                        matlab.ui.control.RadioButton
        VTButton                        matlab.ui.control.RadioButton
        DatoscargadosLabel              matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        GradodelPolinomioLabel          matlab.ui.control.Label
        CoeficientedePearsonmnimoLabel  matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        DropDownPearson                 matlab.ui.control.DropDown
        ResultadosLabel                 matlab.ui.control.Label
        CoeficientedecorrelacinPearsonLabel  matlab.ui.control.Label
        Pearson                         matlab.ui.control.NumericEditField
        FileName                        matlab.ui.control.EditField
        DatosCargadosTable              matlab.ui.control.Table
        CalcularRegresionButton         matlab.ui.control.Button
        resultadosCoef                  matlab.ui.control.Table
    end

    
    properties (Access = private)
        C; % Tabla de datos en formato celdas
        mVC; % datos en celda de mV
        TC;% datos en celda de T
        T;% datos num T
        mV;% datos num mV
    end
    

    methods (Access = private)

        % Button pushed function: AbrirButton
        function AbrirButtonPushed(app, event)
            [file,path] = uigetfile('*.csv');   
            if isequal(file,0)
               disp('Se canceló la selección de archivo del usuario');
               app.FileName.Value = 'No se ha seleccionado ningún archivo';
               app.DatosCargadosTable.delete;
            else
               %disp(['Se importo el archivo: ', fullfile(path,file)]);
               app.FileName.Value = file;
                aux = readtable(fullfile(path,file),'Format','%f%f');
                app.C = table2cell(aux);
                app.DatosCargadosTable.Data=app.C;
                app.DatosCargadosTable.Enable = 'on';
                app.mVC = app.C(:,1);
                %disp(mVC)
                app.TC = app.C(:,2);
                %disp(TC)
                app.T = cell2mat(app.TC);
                app.mV = cell2mat(app.mVC);            
            end
        end

        % Value changed function: DropDownPearson
        function DropDownPearsonValueChanged(app, event)

        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            if isempty(str2double(value))
                set(src,'string','0');
                warndlg('Input must be numerical');
            else
                if floor(value) ~= value
                    warndlg('El valor del grado debe ser un numero entero');
                else
                    app.EditField.Value = floor(value);
                end
            
            end

        end

        % Selection changed function: CalcularporButtonGroup
        function CalcularporButtonGroupSelectionChanged(app, event)

            selectedButton = app.CalcularporButtonGroup.SelectedObject;
            if selectedButton == app.GradoButton
                set(app.GradodelPolinomioLabel,'visible',1);
                set(app.EditField,'visible', 1);
                set(app.CoeficientedePearsonmnimoLabel,'visible',0);
                set(app.DropDownPearson,'visible',0);
            else
                set(app.GradodelPolinomioLabel,'visible',0);
                set(app.EditField,'visible', 0);
                set(app.CoeficientedePearsonmnimoLabel,'visible',1);
                set(app.DropDownPearson,'visible',1);
            end
            
        end

        % Button pushed function: CalcularRegresionButton
        function CalcularRegresionButtonPushed(app, event)
            
            if ~isempty(app.C)
                if  app.TemperaturacomovariableButtonGroup.SelectedObject==app.TVButton 
                    x=app.mV;
                    y=app.T;
                    
                    app.UIAxes.XLabel.String='Voltaje (mV)';
                    app.UIAxes.YLabel.String='Temperatura (°C)';
                else
                    y=app.mV;
                    x=app.T;
                    
                    app.UIAxes.YLabel.String='Voltaje (mV)';
                    app.UIAxes.XLabel.String='Temperatura (°C)';
                end
                
                if app.CalcularporButtonGroup.SelectedObject==app.GradoButton
                    resultados = regresionPolinomial(x,y,app.EditField.Value);
                    dim = size(resultados);
                    indx = zeros(dim);
                    
                    for i=1:1:(dim(2))
                        indx(i) = i-1;
                    end
                    %disp(x)
                    %disp(y)
                    %disp(resultados)
                    %disp(indx)
            
                    resultmatrix = [indx; resultados];
                    resultmatrix = transpose(resultmatrix);
                    app.resultadosCoef.Data=resultmatrix;
                    app.resultadosCoef.Enable = 'on';
                    y_reg = evaluate(resultados, x);
                    plot(app.UIAxes, x,y,'x',x,y_reg,'--');
                    legend(app.UIAxes, 'Datos del termopar','Curva ajustada');
                    app.Pearson.Value = pearson(y, y_reg);
                    %bug
                else
                    coefPmin =app.DropDownPearson.Value;
                    pearsonvaluefinal=0;
                    thisy_reg=[];
                    thisresultmatrix =[];
                    for i=1:1:20
                         % aqui correr max 20 veces la regresion hasta encontrar datos cercanos al coef de pearson deseado.   
                        %con el grado i,
                        resultados = regresionPolinomial(x,y,i);
                        dim = size(resultados);
                        indx = zeros(dim);
                        
                        for i=1:1:(dim(2))
                            indx(i) = i-1;
                        end
    
                        resultmatrix = [indx; resultados];
                        resultmatrix = transpose(resultmatrix);
                        %disp(resultmatrix)
                        y_reg = evaluate(resultados, x);
                        pearsonvalue = pearson(y, y_reg);
                        if pearsonvalue>=coefPmin
                            pearsonvaluefinal=pearsonvalue;
                            thisy_reg = y_reg;
                             thisresultmatrix=resultmatrix;
                            break;
                        elseif   pearsonvaluefinal==0
                             pearsonvaluefinal = pearsonvalue;
                             thisy_reg = y_reg;
                             thisresultmatrix=resultmatrix;
                             
                        else
                        %todo caso aun no llegamos a la meta, pero checar si es el mejor.
                            if pearsonvalue>pearsonvaluefinal
                                pearsonvaluefinal = pearsonvalue;
                             thisy_reg = y_reg;
                             thisresultmatrix=resultmatrix;
                            end
                        end


                    end
                    app.Pearson.Value = pearsonvaluefinal;
                    app.resultadosCoef.Data=thisresultmatrix;
                    app.resultadosCoef.Enable = 'on';
                    plot(app.UIAxes, x,y,'x',x,thisy_reg,'--');
                    legend(app.UIAxes, 'Datos del termopar','Curva ajustada');
                end
            else
                warndlg('No hay valores en la tabla. Por favor cargue un archivo de datos.');
            end


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CaracterizacindeTermoparesUIFigure = uifigure;
            app.CaracterizacindeTermoparesUIFigure.Name = 'Caracterización de Termopares';
            app.SeleccionarArchivoLabel = uilabel(app.CaracterizacindeTermoparesUIFigure);
            app.SeleccionarArchivoLabel.Text = 'Seleccionar Archivo';
            app.AbrirButton = uibutton(app.CaracterizacindeTermoparesUIFigure, 'push');
            app.AbrirButton.ButtonPushedFcn = createCallbackFcn(app, @AbrirButtonPushed, true);
            app.AbrirButton.Text = 'Abrir';
            app.CalcularporButtonGroup = uibuttongroup(app.CaracterizacindeTermoparesUIFigure);
            app.CalcularporButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CalcularporButtonGroupSelectionChanged, true);
            app.CalcularporButtonGroup.Title = 'Calcular por:';
            app.GradoButton = uiradiobutton(app.CalcularporButtonGroup);
            app.GradoButton.Text = 'Grado';
            app.GradoButton.Value = true;
            app.CoeficientedePearsonButton = uiradiobutton(app.CalcularporButtonGroup);
            app.CoeficientedePearsonButton.Text = 'Coeficiente de Pearson';
            app.TemperaturacomovariableButtonGroup = uibuttongroup(app.CaracterizacindeTermoparesUIFigure);
            app.TemperaturacomovariableButtonGroup.Title = 'Temperatura como variable';
            app.TVButton = uiradiobutton(app.TemperaturacomovariableButtonGroup);
            app.TVButton.Text = 'T(V)';
            app.TVButton.Value = true;
            app.VTButton = uiradiobutton(app.TemperaturacomovariableButtonGroup);
            app.VTButton.Text = 'V(T)';
            app.DatoscargadosLabel = uilabel(app.CaracterizacindeTermoparesUIFigure);
            app.DatoscargadosLabel.Text = 'Datos cargados';
            app.UIAxes = uiaxes(app.CaracterizacindeTermoparesUIFigure);
            title(app.UIAxes, 'Datos y regresión')
            xlabel(app.UIAxes, 'T')
            ylabel(app.UIAxes, 'mV')
            app.GradodelPolinomioLabel = uilabel(app.CaracterizacindeTermoparesUIFigure);
            app.GradodelPolinomioLabel.Text = 'Grado del Polinomio';
            app.CoeficientedePearsonmnimoLabel = uilabel(app.CaracterizacindeTermoparesUIFigure);
            app.CoeficientedePearsonmnimoLabel.Visible = 'off';
            app.CoeficientedePearsonmnimoLabel.Text = 'Coeficiente de Pearson mínimo ';
            app.EditField = uieditfield(app.CaracterizacindeTermoparesUIFigure, 'numeric');
            app.EditField.Limits = [0 Inf];
            app.EditField.RoundFractionalValues = 'on';
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 3;
            app.DropDownPearson = uidropdown(app.CaracterizacindeTermoparesUIFigure);
            app.DropDownPearson.Items = {'0.8', '0.85', '0.9', '0.95', '0.99', '0.995', '0.999', '0.9995', '0.9999'};
            app.DropDownPearson.ItemsData = {'0.8', '0.85', '0.9', '0.95', '0.99', '0.995', '0.999', '0.9995', '0.9999'};
            app.DropDownPearson.ValueChangedFcn = createCallbackFcn(app, @DropDownPearsonValueChanged, true);
            app.DropDownPearson.Visible = 'off';
            app.DropDownPearson.Value = '0.85';
            app.ResultadosLabel = uilabel(app.CaracterizacindeTermoparesUIFigure);
            app.ResultadosLabel.Text = 'Resultados';
            app.CoeficientedecorrelacinPearsonLabel = uilabel(app.CaracterizacindeTermoparesUIFigure);
            app.CoeficientedecorrelacinPearsonLabel.Text = 'Coeficiente de correlación (Pearson)';
            app.Pearson = uieditfield(app.CaracterizacindeTermoparesUIFigure, 'numeric');
            app.Pearson.Editable = 'off';
            app.FileName = uieditfield(app.CaracterizacindeTermoparesUIFigure, 'text');
            app.FileName.Editable = 'off';
            app.FileName.Value = 'No se ha seleccionado ningún archivo';
            app.DatosCargadosTable = uitable(app.CaracterizacindeTermoparesUIFigure);
            app.DatosCargadosTable.ColumnName = {'Minivoltios (mV)'; 'Temperatura (°C)'};
            app.DatosCargadosTable.RowName = {};
            app.DatosCargadosTable.Enable = 'off';
            app.CalcularRegresionButton = uibutton(app.CaracterizacindeTermoparesUIFigure, 'push');
            app.CalcularRegresionButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularRegresionButtonPushed, true);
            app.CalcularRegresionButton.Text = 'Calcular Regresion';
            app.resultadosCoef = uitable(app.CaracterizacindeTermoparesUIFigure);
            app.resultadosCoef.ColumnName = {'Grado'; 'Coeficiente'};
            app.resultadosCoef.RowName = {};
            app.resultadosCoef.Enable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Caracterizacion_de_Termopares

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CaracterizacindeTermoparesUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CaracterizacindeTermoparesUIFigure)
        end
    end
end